classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Bita_Wave                 matlab.ui.control.UIAxes
        modulated                 matlab.ui.control.UIAxes
        carrier_1                 matlab.ui.control.UIAxes
        demodulated               matlab.ui.control.UIAxes
        bittime                   matlab.ui.control.EditField
        BitTimeLabel              matlab.ui.control.Label
        carrier_freq              matlab.ui.control.EditField
        CarrierFrequencyLabel     matlab.ui.control.Label
        roll_of_factor            matlab.ui.control.EditField
        RollofffactorLabel        matlab.ui.control.Label
        no                        matlab.ui.control.EditField
        NoLabel                   matlab.ui.control.Label
        ProcessSIgnalButton       matlab.ui.control.Button
        QPSKModulationandDemodulationLabel  matlab.ui.control.Label
        carrier_2                 matlab.ui.control.UIAxes
        A                         matlab.ui.control.EditField
        AmplitudeLabel            matlab.ui.control.Label
        bittransfer               matlab.ui.control.EditField
        NoofBitstotransferLabel   matlab.ui.control.Label
        BinaryDataWaveLabel       matlab.ui.control.Label
        ModulatedQPSKSignalLabel  matlab.ui.control.Label
        DemodulatedSignalLabel    matlab.ui.control.Label
        Cariersignal1Label        matlab.ui.control.Label
        Cariersignal2Label        matlab.ui.control.Label
        BER                       matlab.ui.control.UIAxes
        BiterrorprobabilityLabel  matlab.ui.control.Label
        bw                        matlab.ui.control.Label
        pb                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessSIgnalButton
        function ProcessSIgnalButtonPushed(app, event)
           cla(app.modulated);
           amplitude = str2num(app.A.Value); %from gui (input)
           N0 = str2num(app.no.Value); %input
           Tb=str2num(app.bittime.Value); %bit time from input gui
           t=0:(Tb/100):Tb;
           fc=str2num(app.carrier_freq.Value);  %frequency of carrier can taken from gui

           c1=amplitude*sqrt(2/Tb)*cos(2*pi*fc*t);
           c2=amplitude*sqrt(2/Tb)*sin(2*pi*fc*t);
           
           r = str2num(app.roll_of_factor.Value);  %input roll off factor
           bandw = (1+r)/(Tb*2);
           app.bw.Text=strcat("BandWidth is ",num2str(bandw),"Hz");
           
           Energy = amplitude*amplitude*Tb/2;
           
           BEP = qfunc(sqrt(Energy*2/N0));
           app.pb.Text=strcat("Bit Error Probability is ",num2str(BEP)); 

           N=str2num(app.bittransfer.Value);  %Number of bits you want to send input from gui
           m=rand(1,N);
           t1=0;t2=Tb;
           for i=1:2:(N-1)
            t=[t1:(Tb/100):t2];
            if m(i)>0.5
                m(i)=1;
                m_s=ones(1,length(t));
            else
                m(i)=0;
                m_s=-1*ones(1,length(t));
            end
             %odd bits modulated signal
            odd_sig(i,:)=c1.*m_s;
            if m(i+1)>0.5 
               m(i+1)=1;
               m_s=ones(1,length(t));
            else
               m(i+1)=0;
               m_s=-1*ones(1,length(t));
            end
             %even bits modulated signal
            even_sig(i,:)=c2.*m_s;
             %qpsk signal
            qpsk=odd_sig+even_sig;
            hold(app.modulated,'on');
            plot(app.modulated,t,qpsk(i,:));           
            t1=t1+(Tb+.01); t2=t2+(Tb+.01);
           end
           hold(app.modulated,'off');
           
           stem(app.Bita_Wave,m);
           plot(app.carrier_1,c1);
           plot(app.carrier_2,c2);
           t1=0;
           t2=Tb;
           for i=1:N-1
           t=[t1:(Tb/100):t2];
           %correlator
           x1=sum(c1.*qpsk(i,:));
           x2=sum(c2.*qpsk(i,:));
           %decision device
           if (x1>0&&x2>0)
           demod(i)=1;
           demod(i+1)=1;
           elseif (x1>0&&x2<0)
           demod(i)=1;
           demod(i+1)=0;
           elseif (x1<0&&x2<0)
           demod(i)=0;
           demod(i+1)=0;
           elseif (x1<0&&x2>0)
           demod(i)=0;
           demod(i+1)=1;
           end
           t1=t1+(Tb+.01); t2=t2+(Tb+.01);
           end
           stem(app.demodulated,demod);
           
           N = 1e7; %10^7 samples taken for simulation
           E = 0:.5:12; %these are the values of Eb/N0 estimated
           c = [-1 1]; %source c of prescribed values
           n_error = zeros(1,length(E)); %assigning 0 to all values of n_error
           for i=1:length(E)
              m = randsrc(1,N,c); %generating 10^7 samples from the source c
              n = 1/sqrt(2)*10^(-E(i)/20) * randn(1,N);
              x = m+n;
               
              for j=1:N
                  if (x(j)>0)
                      x(j)=1;
                  else
                      x(j)=-1;
                  end
              end
              counter = 0;
              for k=1:N
                  if (x(k)~=m(k)) %checking bit errors
                      counter=counter+1; %counting errors
                  end
              end
              n_error(i) = counter; %storing errors for each value of E
            end

           snr = 10.^(E./10); 
           P = qfunc(sqrt(2*snr)); %theoretical calculation of BEP
           semilogy(app.BER,E,P,'LineWidth',1.5); %Plotting the theoretical BEP
           BER = n_error/N; %dividing no. of bits in error by total N samples
           hold(app.BER,"on");
           semilogy(app.BER,E,BER,'O','LineWidth',1.5); %Plotting the SIMULATED BEP
           hold(app.BER,"off");
           legend(app.BER,'\color{white} Theoretical','\color{white} Simulation');
           
%xlabel('Eb/No (dB)','Interpreter','LaTeX');
%ylabel('BEP');
%title ('Bit Error Probability for QPSK Demodulation');
%legend('Theoretical','Simulation');
%grid on
           
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Bita_Wave = uiaxes(app.UIFigure);
            title(app.Bita_Wave, '')
            xlabel(app.Bita_Wave, '(n)')
            ylabel(app.Bita_Wave, 'b(n)')
            app.modulated = uiaxes(app.UIFigure);
            title(app.modulated, '')
            xlabel(app.modulated, 'time')
            ylabel(app.modulated, 'S(t)')
            app.carrier_1 = uiaxes(app.UIFigure);
            title(app.carrier_1, '')
            xlabel(app.carrier_1, 'time')
            ylabel(app.carrier_1, 'C1(t)')
            app.demodulated = uiaxes(app.UIFigure);
            title(app.demodulated, '')
            xlabel(app.demodulated, 'n')
            ylabel(app.demodulated, 'b(n)')
            app.bittime = uieditfield(app.UIFigure, 'text');
            app.BitTimeLabel = uilabel(app.UIFigure);
            app.BitTimeLabel.Text = 'Bit Time';
            app.carrier_freq = uieditfield(app.UIFigure, 'text');
            app.CarrierFrequencyLabel = uilabel(app.UIFigure);
            app.CarrierFrequencyLabel.Text = 'Carrier Frequency';
            app.roll_of_factor = uieditfield(app.UIFigure, 'text');
            app.RollofffactorLabel = uilabel(app.UIFigure);
            app.RollofffactorLabel.Text = 'Roll-off factor';
            app.no = uieditfield(app.UIFigure, 'text');
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = 'No';
            app.ProcessSIgnalButton = uibutton(app.UIFigure, 'push');
            app.ProcessSIgnalButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSIgnalButtonPushed, true);
            app.ProcessSIgnalButton.Text = 'Process SIgnal';
            app.QPSKModulationandDemodulationLabel = uilabel(app.UIFigure);
            app.QPSKModulationandDemodulationLabel.Text = 'QPSK Modulation and Demodulation';
            app.carrier_2 = uiaxes(app.UIFigure);
            title(app.carrier_2, '')
            xlabel(app.carrier_2, 'time')
            ylabel(app.carrier_2, 'C2(t)')
            app.A = uieditfield(app.UIFigure, 'text');
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.bittransfer = uieditfield(app.UIFigure, 'text');
            app.NoofBitstotransferLabel = uilabel(app.UIFigure);
            app.NoofBitstotransferLabel.Text = 'No of Bits to transfer';
            app.BinaryDataWaveLabel = uilabel(app.UIFigure);
            app.BinaryDataWaveLabel.Text = 'Binary Data Wave';
            app.ModulatedQPSKSignalLabel = uilabel(app.UIFigure);
            app.ModulatedQPSKSignalLabel.Text = 'Modulated QPSK Signal';
            app.DemodulatedSignalLabel = uilabel(app.UIFigure);
            app.DemodulatedSignalLabel.Text = 'Demodulated Signal';
            app.Cariersignal1Label = uilabel(app.UIFigure);
            app.Cariersignal1Label.Text = 'Carier signal 1';
            app.Cariersignal2Label = uilabel(app.UIFigure);
            app.Cariersignal2Label.Text = 'Carier signal 2';
            app.BER = uiaxes(app.UIFigure);
            title(app.BER, '')
            xlabel(app.BER, 'Eb/N0 (SNR)')
            ylabel(app.BER, 'BER')
            app.BiterrorprobabilityLabel = uilabel(app.UIFigure);
            app.BiterrorprobabilityLabel.Text = 'Bit error probability';
            app.bw = uilabel(app.UIFigure);
            app.pb = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end