classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Bita_Wave                       matlab.ui.control.UIAxes
        modulated                       matlab.ui.control.UIAxes
        carrier_1                       matlab.ui.control.UIAxes
        demodulated                     matlab.ui.control.UIAxes
        bittime                         matlab.ui.control.EditField
        BitTimeLabel                    matlab.ui.control.Label
        carrier_freq_1                  matlab.ui.control.EditField
        CarrierFrequencyofsignal1Label  matlab.ui.control.Label
        roll_of_factor                  matlab.ui.control.EditField
        RollofffactorLabel              matlab.ui.control.Label
        no                              matlab.ui.control.EditField
        NoLabel                         matlab.ui.control.Label
        ProcessSIgnalButton             matlab.ui.control.Button
        BFSKModulationandDemodulationLabel  matlab.ui.control.Label
        carrier_2                       matlab.ui.control.UIAxes
        A                               matlab.ui.control.EditField
        AmplitudeLabel                  matlab.ui.control.Label
        bittransfer                     matlab.ui.control.EditField
        NoofBitstotransferLabel         matlab.ui.control.Label
        BinaryDataWaveLabel             matlab.ui.control.Label
        ModulatedBFSKSignalLabel        matlab.ui.control.Label
        DemodulatedSignalLabel          matlab.ui.control.Label
        Cariersignal1Label              matlab.ui.control.Label
        Cariersignal2Label              matlab.ui.control.Label
        BER                             matlab.ui.control.UIAxes
        BiterrorprobabilityLabel        matlab.ui.control.Label
        carrier_freq_2                  matlab.ui.control.EditField
        CarrierFrequencyofsignal2Label  matlab.ui.control.Label
        bcs                             matlab.ui.control.Label
        bncs                            matlab.ui.control.Label
        pc                              matlab.ui.control.Label
        pnc                             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessSIgnalButton
        function ProcessSIgnalButtonPushed(app, event)
            Tb= str2num(app.bittime.Value);  %INPUT FROM GUI   Bit Time
            A = str2num(app.A.Value); %INPUT FROM GUI   Amplitude
            fc1=str2num(app.carrier_freq_1.Value); %INPUT FROM GUI   Frequency for bit 0
            fc2=str2num(app.carrier_freq_2.Value); %INPUT FROM GUI   Frequency for bit 1
            N0 =str2double(app.no.Value); %Noise Power Spectral Density...INPUT FROM GUI
            r = 0; %roll-off factor...INPUT FROM GUI
            Eb = (A.^2)*Tb/2; %Calculation of bit Energy
            t=0:(Tb/100):Tb;
            c1=sqrt((Eb*2)/Tb)*sin(2*pi*fc1*t); %Generated carrier signal 1
            c2=sqrt((Eb*2)/Tb)*sin(2*pi*fc2*t); %Generated carrier signal 2
            
            % generate message signal
            N=str2num(app.bittransfer.Value); % INPUT FROM GUI (No. of bits you want to send)
            m=rand(1,N); % Generating total N numbers randomly between 0 and 1
            t1=0;
            t2=Tb;
            for i=1:N
                t = t1:(Tb/100):t2;
                if m(i)>0.5
                    m(i)=1;
                    m_s=ones(1,length(t));
                    inverted_m_s=zeros(1,length(t));
                else
                    m(i)=0;
                    m_s=zeros(1,length(t));
                    inverted_m_s=ones(1,length(t));
                end
                message(i,:) = m_s; %meaning one row, many columns at a time
                %Multiplier
                fsk_sig1(i,:) = c2.*m_s; 
                fsk_sig2(i,:) = c1.*inverted_m_s;
                fsk = fsk_sig1 + fsk_sig2;
                %plotting the message signal and the modulated signal
                %plot(app.Bita_Wave,t,message(i,:)); %plotting the message which will be sent
                stem(app.Bita_Wave,m);
                plot(app.carrier_1,c1);
                plot(app.carrier_2,c2);
                plot(app.modulated,t,fsk(i,:)); %plotting the final bfsk signal
                hold(app.modulated,"on");
                
                t1=t1+(Tb+.01); %incrementing the time pointer t1 by 1 for next bit
                t2=t2+(Tb+.01); %incrementing the time pointer t2 by 1 for next bit
            end
            hold(app.modulated,"off");
                
            
            %Bandwidth for coherent signalling
            bw_coherent = (fc2 - fc1)*(1.5 + r);
            app.bcs.Text=strcat("Bandwidth for coherent signalling is ",num2str(bw_coherent),"Hz");
           
            
            %Bandwidth for non-coherent signalling
            bw_non_coherent = (fc2 - fc1)*(2 + r);
            app.bncs.Text=strcat("Bandwidth for non-coherent signalling is ",num2str(bw_non_coherent),'Hz');
            disp(bw_non_coherent);
            
            Pb = qfunc(sqrt(Eb./N0));
            app.pc.Text=strcat("Bit Error Probability(coherent orthogonal signalling) is ",num2str(Pb));
            
            %Bit Error Probability for non-coherent orthogonal signalling
            P_b_non_c = (1/2).*exp(-Eb./(2.*N0));
            app.pnc.Text=strcat("Bit Error Probability(non-coherent orthogonal signalling) is ",num2str(P_b_non_c));
            
            
            % FSK Demodulation - Here we are doing coherent detection of orthogonal
            % signalling
            t1 = 0;
            t2 = Tb;
            for i=1:N
                t = t1:(Tb/100):t2;
                %correlator
                x1 = sum(c2.*fsk_sig1(i,:));
                x2 = sum(c1.*fsk_sig2(i,:));
                x = x1 - x2;
                %decision device
                if x>0
                    demod(i)=1;
                else
                    demod(i)=0;
                end
                t1 = t1 + (Tb+.01);
                t2 = t2 + (Tb+.01);
            end
            stem(app.demodulated,demod);
            
            %Bit Error Probability of BFSK using AWGN
            %Input Bits Generations
            N = 1e7; %using 10^7 samples for simulation
            m = randi([0 1],1,N); %Generated 10^7 random samples between 0 and 1
            %BFSK Mapping
            for i=1:N
                if m(i)==0
                    x(i) = sqrt(-1);
                else
                    x(i) = 1;
                end
            end
            
            %AWGN Channel
            BER_simulation = []; %declaring empty array to store simulation result
            BER_theoretical = []; %declaring empty array to store theoretical equation result
            BER_upper = []; %declaring empty array to store upper bound of BER result
            
            for EbN0_in_dB = 0:0.5:15
                EbN0 = 10^(EbN0_in_dB/10);
                n = (1/sqrt(2)).*[randn(1,N) + sqrt(-1)*randn(1,N)]; %Complex Gaussian Noise
                sigma = sqrt(1/EbN0); %Noise standard deviation
                r = x + sigma.*n; %Received Signal
            
                %Detection of received signal
                m_cap = (real(r)>imag(r)); %if real(r)>imag(r) then consider bit as 1 else 0
                %BER Calculation
                BER_sim1 = sum(m~=m_cap)/N; %ratio of total bits in error to total N transmitted bits
                BER_simulation = [BER_simulation BER_sim1]; 
                BER_th_Qfunction = qfunc(sqrt(EbN0)); %calculation of theoretical BER using Q-function
                BER_theoretical = [BER_theoretical BER_th_Qfunction]; %appending BER_th_Qfunction to BER_theoretical array
                BER_th_upperbound = 0.5*exp(-(EbN0/2)); %calculation of theoretical BER upper bound using exp-function
                BER_upper = [BER_upper BER_th_upperbound]; %appending BER_th_upperbound to BER_upper array
            end
            
            %Plotting the simulation result
            EbN0_in_dB = 0:0.5:15; %values of SNR in db on for x-axis
            semilogy(app.BER,EbN0_in_dB,BER_theoretical,'-',EbN0_in_dB,BER_simulation,'p');
            legend(app.BER,'\color{white} Theoretical','\color{white} Simulation');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Bita_Wave = uiaxes(app.UIFigure);
            title(app.Bita_Wave, '')
            xlabel(app.Bita_Wave, '(n)')
            ylabel(app.Bita_Wave, 'b(n)')
            app.modulated = uiaxes(app.UIFigure);
            title(app.modulated, '')
            xlabel(app.modulated, 'time')
            ylabel(app.modulated, 'S(t)')
            app.carrier_1 = uiaxes(app.UIFigure);
            title(app.carrier_1, '')
            xlabel(app.carrier_1, 'time')
            ylabel(app.carrier_1, 'C1(t)')
            app.demodulated = uiaxes(app.UIFigure);
            title(app.demodulated, '')
            xlabel(app.demodulated, 'n')
            ylabel(app.demodulated, 'b(n)')
            app.bittime = uieditfield(app.UIFigure, 'text');
            app.BitTimeLabel = uilabel(app.UIFigure);
            app.BitTimeLabel.Text = 'Bit Time';
            app.carrier_freq_1 = uieditfield(app.UIFigure, 'text');
            app.CarrierFrequencyofsignal1Label = uilabel(app.UIFigure);
            app.CarrierFrequencyofsignal1Label.Text = 'Carrier Frequency of signal 1';
            app.roll_of_factor = uieditfield(app.UIFigure, 'text');
            app.RollofffactorLabel = uilabel(app.UIFigure);
            app.RollofffactorLabel.Text = 'Roll-off factor';
            app.no = uieditfield(app.UIFigure, 'text');
            app.NoLabel = uilabel(app.UIFigure);
            app.NoLabel.Text = 'No';
            app.ProcessSIgnalButton = uibutton(app.UIFigure, 'push');
            app.ProcessSIgnalButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessSIgnalButtonPushed, true);
            app.ProcessSIgnalButton.Text = 'Process SIgnal';
            app.BFSKModulationandDemodulationLabel = uilabel(app.UIFigure);
            app.BFSKModulationandDemodulationLabel.Text = 'BFSK Modulation and Demodulation';
            app.carrier_2 = uiaxes(app.UIFigure);
            title(app.carrier_2, '')
            xlabel(app.carrier_2, 'time')
            ylabel(app.carrier_2, 'C2(t)')
            app.A = uieditfield(app.UIFigure, 'text');
            app.AmplitudeLabel = uilabel(app.UIFigure);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.bittransfer = uieditfield(app.UIFigure, 'text');
            app.NoofBitstotransferLabel = uilabel(app.UIFigure);
            app.NoofBitstotransferLabel.Text = 'No of Bits to transfer';
            app.BinaryDataWaveLabel = uilabel(app.UIFigure);
            app.BinaryDataWaveLabel.Text = 'Binary Data Wave';
            app.ModulatedBFSKSignalLabel = uilabel(app.UIFigure);
            app.ModulatedBFSKSignalLabel.Text = 'Modulated BFSK Signal';
            app.DemodulatedSignalLabel = uilabel(app.UIFigure);
            app.DemodulatedSignalLabel.Text = 'Demodulated Signal';
            app.Cariersignal1Label = uilabel(app.UIFigure);
            app.Cariersignal1Label.Text = 'Carier signal 1';
            app.Cariersignal2Label = uilabel(app.UIFigure);
            app.Cariersignal2Label.Text = 'Carier signal 2';
            app.BER = uiaxes(app.UIFigure);
            title(app.BER, '')
            xlabel(app.BER, 'Eb/N0 (SNR)')
            ylabel(app.BER, 'BER')
            app.BiterrorprobabilityLabel = uilabel(app.UIFigure);
            app.BiterrorprobabilityLabel.Text = 'Bit error probability';
            app.carrier_freq_2 = uieditfield(app.UIFigure, 'text');
            app.CarrierFrequencyofsignal2Label = uilabel(app.UIFigure);
            app.CarrierFrequencyofsignal2Label.Text = 'Carrier Frequency of signal 2';
            app.bcs = uilabel(app.UIFigure);
            app.bncs = uilabel(app.UIFigure);
            app.pc = uilabel(app.UIFigure);
            app.pnc = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end